classdef app < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        NegativeCheckBox          matlab.ui.control.CheckBox
        BrightnessB               matlab.ui.control.Slider
        BrightnessbSliderLabel    matlab.ui.control.Label
        BrightnessA               matlab.ui.control.Slider
        BrightnessaSliderLabel    matlab.ui.control.Label
        LoadImageButton           matlab.ui.control.Button
        ChooseImageDropDown       matlab.ui.control.DropDown
        ChooseImageDropDownLabel  matlab.ui.control.Label
        InputHistogram3           matlab.ui.control.UIAxes
        OutputHistogram3          matlab.ui.control.UIAxes
        InputHistogram2           matlab.ui.control.UIAxes
        OutputHistogram2          matlab.ui.control.UIAxes
        InputHistogram1           matlab.ui.control.UIAxes
        OutputHistogram1          matlab.ui.control.UIAxes
        OutputImage               matlab.ui.control.UIAxes
        InputImage                matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(comp, event)
            nama = comp.ChooseImageDropDown.Value;
            I = imread(nama);
            imshow(I, 'Parent', comp.InputImage);
            imshow(I, 'Parent', comp.OutputImage);
            
            hist = make_histogram(I);
            colorCode = ['r', 'g', 'b'];
            for k = 1 : size(hist,1)
                fig_in = sprintf('comp.InputHistogram%d', k);
                fig_out = sprintf('comp.OutputHistogram%d', k);

                fig_in = eval(fig_in);
                fig_out = eval(fig_out);
                
                if size(hist,1) == 1
                    set_histogram(hist, k, fig_in, "Masukan", 'k');
                    set_histogram(hist, k, fig_out, "Keluaran", 'k');
                else
                    set_histogram(hist, k, fig_in, "Masukan", colorCode(k));
                    set_histogram(hist, k, fig_out, "Keluaran", colorCode(k));
                end
            end


            function [hist] = make_histogram(I)
                [M, N, C] = size(I);
            
                if C == 1
                    % Hanya ada 1 channel; citra grayscale
                    hist = zeros(1, 256);
                elseif C == 3
                    % Ada 3 channel; citra berwarna
                    hist = zeros(3, 256);
                end
                
                
                for k = 1 : C
                    for i = 1 : M
                        for j = 1 : N
                            hist(k, (I(i,j,k) + 1)) = hist(k, (I(i,j,k) + 1)) + 1;
                        end
                    end
                end
            end


            function [] = set_histogram(hist, channel, fig, customTitle, colorCode)
                % Memasukkan hist(channel) ke dalam fig
                cla(fig);          % Hapus histogram yang sudah ada
                hold(fig, 'on');   % Mengaktifkan akses terhadap frame

                bar(fig, 0:255, hist(channel, :), 'FaceColor', colorCode);
                title(fig, sprintf('Histogram %s, Channel %d', customTitle, channel));
                xlabel(fig, 'Nilai Intensitas');
                ylabel(fig, 'Frekuensi');
            end
        end

        % Value changed function: BrightnessA
        function Brightness_a_Changed(comp, event)
            a = event.Value;
            b = comp.BrightnessB.Value;

            I = findobj(comp.InputImage, 'Type', 'image');
            I = I.CData;
            [M, N, C] = size(I);

            bright = zeros(M, N, C);
            for i = 1 : M
                for j = 1 : N
                    for k = 1 : C
                        bright(i,j,k) = a * I(i,j,k) + b;
                    
                        if bright(i,j,k) > 255
                            bright(i,j,k) = 255;
                        elseif bright(i,j,k) < 0
                            bright(i,j,k) = 0;
                        end
                    end
                end
            end
            
            bright = uint8(bright);
            
            out_I = findobj(comp.OutputImage, 'Type', 'image');
            out_I.CData = bright;

            if comp.NegativeCheckBox.Value == 1
                NegativeCheckBoxValueChanged(comp, comp.NegativeCheckBox.Value == 1);
            end
        end

        % Value changed function: BrightnessB
        function Brightness_b_Changed(comp, event)
            a = comp.BrightnessA.Value;
            b = event.Value;

            I = findobj(comp.InputImage, 'Type', 'image');
            I = I.CData;
            [M, N, C] = size(I);

            bright = zeros(M, N, C);
            for i = 1 : M
                for j = 1 : N
                    for k = 1 : C
                        bright(i,j,k) = a * I(i,j,k) + b;
                    
                        if bright(i,j,k) > 255
                            bright(i,j,k) = 255;
                        elseif bright(i,j,k) < 0
                            bright(i,j,k) = 0;
                        end
                    end
                end
            end
            
            bright = uint8(bright);
            
            I_out = findobj(comp.OutputImage, 'Type', 'image');
            I_out.CData = bright;

            if comp.NegativeCheckBox.Value == 1
                NegativeCheckBoxValueChanged(comp, comp.NegativeCheckBox.Value == 1);
            end
        end

        % Value changed function: NegativeCheckBox
        function NegativeCheckBoxValueChanged(comp, event)
            I_out = findobj(comp.OutputImage, 'Type', 'image');
            I_out = I_out.CData;
            [M, N, C] = size(I_out);
            
            neg = zeros(M, N, C);
            for i = 1 : M
                for j = 1 : N
                    for k = 1 : C
                        neg(i,j,k) = 255 - I_out(i,j,k);
                    end
                end
            end
            
            neg = uint8(neg);
            I_out = findobj(comp.OutputImage, 'Type', 'image');
            I_out.CData = neg;
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 1412 665];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.InputImage = uiaxes(comp);
            title(comp.InputImage, 'Citra Masukan')
            comp.InputImage.XTick = [];
            comp.InputImage.YTick = [];
            comp.OutputImage = uiaxes(comp);
            title(comp.OutputImage, 'Citra Keluaran')
            comp.OutputImage.XTick = [];
            comp.OutputImage.YTick = [];
            comp.OutputHistogram1 = uiaxes(comp);
            title(comp.OutputHistogram1, 'Histogram Keluaran (1)')
            xlabel(comp.OutputHistogram1, 'Nilai Intensitas')
            ylabel(comp.OutputHistogram1, 'Frekuensi')
            zlabel(comp.OutputHistogram1, 'Z')
            comp.InputHistogram1 = uiaxes(comp);
            title(comp.InputHistogram1, 'Histogram Masukan (1)')
            xlabel(comp.InputHistogram1, 'Nilai Intensitas')
            ylabel(comp.InputHistogram1, 'Frekuensi')
            zlabel(comp.InputHistogram1, 'Z')
            comp.OutputHistogram2 = uiaxes(comp);
            title(comp.OutputHistogram2, 'Histogram Keluaran (2)')
            xlabel(comp.OutputHistogram2, 'Nilai Intensitas')
            ylabel(comp.OutputHistogram2, 'Frekuensi')
            zlabel(comp.OutputHistogram2, 'Z')
            comp.InputHistogram2 = uiaxes(comp);
            title(comp.InputHistogram2, 'Histogram Masukan (2)')
            xlabel(comp.InputHistogram2, 'Nilai Intensitas')
            ylabel(comp.InputHistogram2, 'Frekuensi')
            zlabel(comp.InputHistogram2, 'Z')
            comp.OutputHistogram3 = uiaxes(comp);
            title(comp.OutputHistogram3, 'Histogram Keluaran (3)')
            xlabel(comp.OutputHistogram3, 'Nilai Intensitas')
            ylabel(comp.OutputHistogram3, 'Frekuensi')
            zlabel(comp.OutputHistogram3, 'Z')
            comp.InputHistogram3 = uiaxes(comp);
            title(comp.InputHistogram3, 'Histogram Masukan (3)')
            xlabel(comp.InputHistogram3, 'Nilai Intensitas')
            ylabel(comp.InputHistogram3, 'Frekuensi')
            zlabel(comp.InputHistogram3, 'Z')
            comp.ChooseImageDropDownLabel = uilabel(comp);
            comp.ChooseImageDropDownLabel.Text = 'Choose Image';
            comp.ChooseImageDropDown = uidropdown(comp);
            comp.ChooseImageDropDown.Items = {'boat.bmp', 'lena.bmp', 'girl.bmp', 'strawberries.tif'};
            comp.ChooseImageDropDown.Value = 'boat.bmp';
            comp.LoadImageButton = uibutton(comp, 'push');
            comp.LoadImageButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @LoadImageButtonPushed, true);
            comp.LoadImageButton.Text = 'Load';
            comp.BrightnessaSliderLabel = uilabel(comp);
            comp.BrightnessaSliderLabel.Text = 'Brightness (a)';
            comp.BrightnessA = uislider(comp);
            comp.BrightnessA.Limits = [1 2];
            comp.BrightnessA.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @Brightness_a_Changed, true);
            comp.BrightnessA.Value = 1;
            comp.BrightnessbSliderLabel = uilabel(comp);
            comp.BrightnessbSliderLabel.Text = 'Brightness (b)';
            comp.BrightnessB = uislider(comp);
            comp.BrightnessB.Limits = [-50 50];
            comp.BrightnessB.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @Brightness_b_Changed, true);
            comp.NegativeCheckBox = uicheckbox(comp);
            comp.NegativeCheckBox.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @NegativeCheckBoxValueChanged, true);
            comp.NegativeCheckBox.Text = '  Negative';
        end
    end
end