classdef app_new < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        ApplyButton                 matlab.ui.control.Button
        OperationDropDown           matlab.ui.control.DropDown
        OperationDropDownLabel      matlab.ui.control.Label
        HistogramDisplayPanel       matlab.ui.container.Panel
        InputHistogramR             matlab.ui.control.UIAxes
        InputHistogramG             matlab.ui.control.UIAxes
        InputHistogramB             matlab.ui.control.UIAxes
        OutputHistogramR            matlab.ui.control.UIAxes
        OutputHistogramG            matlab.ui.control.UIAxes
        OutputHistogramB            matlab.ui.control.UIAxes
        ImageDisplayPanel           matlab.ui.container.Panel
        InputImage                  matlab.ui.control.UIAxes
        OutputImage                 matlab.ui.control.UIAxes
        OperationTabGroup           matlab.ui.container.TabGroup
        BrightnessTab               matlab.ui.container.Tab
        BrightnessAField            matlab.ui.control.NumericEditField
        BrightnessBField            matlab.ui.control.NumericEditField
        OffsetEditFieldLabel        matlab.ui.control.Label
        MultiplierEditFieldLabel    matlab.ui.control.Label
        BrightnessPlot              matlab.ui.control.UIAxes
        NegativeTab                 matlab.ui.container.Tab
        NegativeEnableCheckBox      matlab.ui.control.CheckBox
        NegativePlot                matlab.ui.control.UIAxes
        LogTransformTab             matlab.ui.container.Tab
        LogMultiplierField          matlab.ui.control.NumericEditField
        MultiplierEditFieldLabel_2  matlab.ui.control.Label
        LogPlot                     matlab.ui.control.UIAxes
        PowerTransformTab           matlab.ui.container.Tab
        PowerMultiplierField        matlab.ui.control.NumericEditField
        MultiplierEditFieldLabel_3  matlab.ui.control.Label
        PowerGammaField             matlab.ui.control.NumericEditField
        GammaLabel                  matlab.ui.control.Label
        PowerPlot                   matlab.ui.control.UIAxes
        ContrastStretchingTab       matlab.ui.container.Tab
        CStretchingMinRField        matlab.ui.control.NumericEditField
        CStretchingMinGField        matlab.ui.control.NumericEditField
        CStretchingMinBField        matlab.ui.control.NumericEditField
        CStretchingMaxRField        matlab.ui.control.NumericEditField
        MinimumLabel_6              matlab.ui.control.Label
        CStretchingMaxGField        matlab.ui.control.NumericEditField
        CStretchingMaxBField        matlab.ui.control.NumericEditField
        MinimumLabel_5              matlab.ui.control.Label
        MinimumLabel_4              matlab.ui.control.Label
        MinimumLabel_3              matlab.ui.control.Label
        MinimumLabel_2              matlab.ui.control.Label
        MinimumLabel                matlab.ui.control.Label
        HistogramEqualizationTab    matlab.ui.container.Tab
        HistogramMatchingTab        matlab.ui.container.Tab
        ReferenceHistogramR         matlab.ui.control.UIAxes
        ReferenceHistogramG         matlab.ui.control.UIAxes
        ReferenceHistogramB         matlab.ui.control.UIAxes
        SelectImageButton           matlab.ui.control.Button
        SelectedImageLabel          matlab.ui.control.Label
        ChooseImageLabel            matlab.ui.control.Label
    end

    properties (AbortSet, Access = private, GetObservable, SetObservable)
        pathToFile (1,1) string % Path to the currently loaded image file.
    end
    
    
    methods (Access = private)
        
        function [] = clearDisplays(comp)
            cla(comp.InputImage);
            cla(comp.OutputImage);

            cla(comp.InputHistogramR);
            cla(comp.InputHistogramG);
            cla(comp.InputHistogramB);

            cla(comp.OutputHistogramR);
            cla(comp.OutputHistogramG);
            cla(comp.OutputHistogramB);
        end

        function [] = displayInputHistogram(comp, dispHist)
            cla(comp.InputHistogramR);
            cla(comp.InputHistogramG);
            cla(comp.InputHistogramB);

            if size(dispHist, 1) == 1
                rCol = 'k';
                comp.InputHistogramG.Visible = 'off';
                comp.InputHistogramB.Visible = 'off';
            else
                rCol = 'r';
                comp.InputHistogramG.Visible = 'on';
                comp.InputHistogramB.Visible = 'on';
            end
            
            bar(comp.InputHistogramR, 0:255, dispHist(1, :), 'FaceColor', rCol);
            if size(dispHist, 1) > 1
                bar(comp.InputHistogramG, 0:255, dispHist(2, :), 'FaceColor', 'g');
                bar(comp.InputHistogramB, 0:255, dispHist(3, :), 'FaceColor', 'b');
            end
        end

        function [] = displayOutputHistogram(comp, dispHist)
            cla(comp.OutputHistogramR);
            cla(comp.OutputHistogramG);
            cla(comp.OutputHistogramB);

            if size(dispHist, 1) == 1
                rCol = 'k';
                comp.OutputHistogramG.Visible = 'off';
                comp.OutputHistogramB.Visible = 'off';
            else
                rCol = 'r';
                comp.OutputHistogramG.Visible = 'on';
                comp.OutputHistogramB.Visible = 'on';
            end
            
            bar(comp.OutputHistogramR, 0:255, dispHist(1, :), 'FaceColor', rCol);
            if size(dispHist, 1) > 1
                bar(comp.OutputHistogramG, 0:255, dispHist(2, :), 'FaceColor', 'g');
                bar(comp.OutputHistogramB, 0:255, dispHist(3, :), 'FaceColor', 'b');
            end
        end

        function [] = brightnessRefreshPlot(comp)
            a = comp.BrightnessAField.Value;
            b = comp.BrightnessBField.Value;

            fnHandle = str2func(['@(x)clip(', num2str(a), '*x+', num2str(b), ', 0, 255)']);
            x = linspace(0, 255);
            y = fnHandle(x);

            plot(comp.BrightnessPlot, x, y);
        end
        
        function [] = negativeRefreshPlot(comp)
            if comp.NegativeEnableCheckBox.Value == 1
                fnHandle = str2func('@(x)255 - x');
            else
                fnHandle = str2func('@(x)x');
            end

            x = linspace(0, 255);
            y = fnHandle(x);

            plot(comp.NegativePlot, x, y);
        end

        function [] = logRefreshPlot(comp)
            c = comp.LogMultiplierField.Value;

            fnHandle = str2func(['@(x)clip(', num2str(c) '*log10(double(1+x)), 0, 255)']);
            x = linspace(0, 255);
            y = fnHandle(x);

            plot(comp.LogPlot, x, y);
        end

        function [] = powerRefreshPlot(comp)
            c = comp.PowerMultiplierField.Value;
            g = comp.PowerGammaField.Value;

            fnHandle = str2func(['@(x)clip(', num2str(c), '*x.^', num2str(g), ', 0, 255)']);
            x = linspace(0, 255);
            y = fnHandle(x);

            plot(comp.PowerPlot, x, y);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            brightnessRefreshPlot(comp);
            negativeRefreshPlot(comp);
            logRefreshPlot(comp);
            powerRefreshPlot(comp);
        end

        % Button pushed function: SelectImageButton
        function SelectImageButtonPushed(comp, event)
            [fileName, fileLoc] = uigetfile({'*.bmp;*.tif;*.tiff', 'All files'; '*.bmp', 'BMP files'; '*.tif;*.tiff', 'TIFF files'});
            if fileLoc ~= 0
                clearDisplays(comp);

                comp.pathToFile = join([fileLoc, fileName], '\');
                comp.SelectedImageLabel.Text = comp.pathToFile;
                I = imread(comp.pathToFile);
                imshow(I, 'Parent', comp.InputImage);
    
                inputHist = make_histogram(I);
                displayInputHistogram(comp, inputHist);
            end
        end

        % Selection change function: OperationTabGroup
        function OperationTabGroupSelectionChanged(comp, event)
            comp.OperationTabGroup.SelectedTab = event.OldValue;
        end

        % Value changed function: OperationDropDown
        function OperationDropDownValueChanged(comp, event)
            switch comp.OperationDropDown.ValueIndex
                case 1
                    comp.OperationTabGroup.SelectedTab = comp.BrightnessTab;
                case 2
                    comp.OperationTabGroup.SelectedTab = comp.NegativeTab;
                case 3
                    comp.OperationTabGroup.SelectedTab = comp.LogTransformTab;
                case 4
                    comp.OperationTabGroup.SelectedTab = comp.PowerTransformTab;
                case 5
                    comp.OperationTabGroup.SelectedTab = comp.ContrastStretchingTab;
                case 6
                    comp.OperationTabGroup.SelectedTab = comp.HistogramEqualizationTab;
                case 7
                    comp.OperationTabGroup.SelectedTab = comp.HistogramMatchingTab;
            end
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(comp, event)
            I = imread(comp.pathToFile);

            switch comp.OperationDropDown.ValueIndex
                case 1
                    a = comp.BrightnessAField.Value;
                    b = comp.BrightnessBField.Value;
                    I2 = brightening(I, a, b);
                case 2
                    I2 = I;
                    if comp.NegativeEnableCheckBox.Value
                        I2 = negative(I2);
                    end
                case 3
                    c = comp.LogMultiplierField.Value;
                    I2 = logarithm(I, c);
                case 4
                    c = comp.PowerMultiplierField.Value;
                    g = comp.PowerGammaField.Value;
                    I2 = power_gamma(I, c, g);
                case 5
                    [I2, minVal, maxVal] = contrast_stretching(I);
                    comp.CStretchingMinRField.Value = minVal(1);
                    comp.CStretchingMaxRField.Value = maxVal(1);
                    if size(minVal, 1) > 1
                        comp.CStretchingMinGField.Value = minVal(2);
                        comp.CStretchingMinBField.Value = minVal(3);

                        comp.CStretchingMaxGField.Value = maxVal(2);
                        comp.CStretchingMaxBField.Value = maxVal(3);

                        comp.CStretchingMinGField.Visible = 'on';
                        comp.CStretchingMinBField.Visible = 'on';
                        comp.CStretchingMaxGField.Visible = 'on';
                        comp.CStretchingMaxBField.Visible = 'on';
                    else
                        comp.CStretchingMinGField.Visible = 'off';
                        comp.CStretchingMinBField.Visible = 'off';
                        comp.CStretchingMaxGField.Visible = 'off';
                        comp.CStretchingMaxBField.Visible = 'off';
                    end
            end

            outHist = make_histogram(I2);
            displayOutputHistogram(comp, outHist);
            imshow(I2, 'Parent', comp.OutputImage);
        end

        % Value changed function: BrightnessAField
        function BrightnessAFieldValueChanged(comp, event)
            brightnessRefreshPlot(comp);
        end

        % Value changed function: BrightnessBField
        function BrightnessBFieldValueChanged(comp, event)
            brightnessRefreshPlot(comp);
        end

        % Value changed function: NegativeEnableCheckBox
        function NegativeEnableCheckBoxValueChanged(comp, event)
            negativeRefreshPlot(comp);
        end

        % Value changed function: LogMultiplierField
        function LogMultiplierFieldValueChanged(comp, event)
            logRefreshPlot(comp);
        end

        % Value changed function: PowerMultiplierField
        function PowerMultiplierFieldValueChanged(comp, event)
            powerRefreshPlot(comp);
        end

        % Value changed function: PowerGammaField
        function PowerGammaFieldValueChanged(comp, event)
            powerRefreshPlot(comp);
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            comp.ApplyButton.Enable = comp.pathToFile ~= '';
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 1343 601];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.ChooseImageLabel = uilabel(comp);
            comp.ChooseImageLabel.Text = 'Choose image';
            comp.SelectedImageLabel = uilabel(comp);
            comp.SelectedImageLabel.Enable = 'off';
            comp.SelectedImageLabel.Text = 'No image selected';
            comp.SelectImageButton = uibutton(comp, 'push');
            comp.SelectImageButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @SelectImageButtonPushed, true);
            comp.SelectImageButton.Text = 'Select';
            comp.OperationTabGroup = uitabgroup(comp);
            comp.OperationTabGroup.SelectionChangedFcn = matlab.apps.createCallbackFcn(comp, @OperationTabGroupSelectionChanged, true);
            comp.BrightnessTab = uitab(comp.OperationTabGroup);
            comp.BrightnessTab.Title = 'Brightness';
            comp.BrightnessPlot = uiaxes(comp.BrightnessTab);
            title(comp.BrightnessPlot, 'Level mapping')
            xlabel(comp.BrightnessPlot, 'Before')
            ylabel(comp.BrightnessPlot, 'After')
            comp.BrightnessPlot.XLim = [0 255];
            comp.BrightnessPlot.YLim = [0 255];
            comp.BrightnessPlot.ZLim = [0 1];
            comp.BrightnessPlot.XTick = [0 63 127 191 255];
            comp.BrightnessPlot.XTickLabel = {'0'; '63'; '127'; '191'; '255'};
            comp.BrightnessPlot.YTick = [0 63 127 191 255];
            comp.BrightnessPlot.YTickLabel = {'0'; '63'; '127'; '191'; '255'};
            comp.BrightnessPlot.ZTick = [];
            comp.BrightnessPlot.ZTickLabel = '';
            comp.MultiplierEditFieldLabel = uilabel(comp.BrightnessTab);
            comp.MultiplierEditFieldLabel.Text = 'Multiplier';
            comp.OffsetEditFieldLabel = uilabel(comp.BrightnessTab);
            comp.OffsetEditFieldLabel.Text = 'Offset';
            comp.BrightnessBField = uieditfield(comp.BrightnessTab, 'numeric');
            comp.BrightnessBField.RoundFractionalValues = 'on';
            comp.BrightnessBField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @BrightnessBFieldValueChanged, true);
            comp.BrightnessAField = uieditfield(comp.BrightnessTab, 'numeric');
            comp.BrightnessAField.Limits = [0 Inf];
            comp.BrightnessAField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @BrightnessAFieldValueChanged, true);
            comp.BrightnessAField.Value = 1;
            comp.NegativeTab = uitab(comp.OperationTabGroup);
            comp.NegativeTab.Title = 'Negative';
            comp.NegativePlot = uiaxes(comp.NegativeTab);
            title(comp.NegativePlot, 'Level mapping')
            xlabel(comp.NegativePlot, 'Before')
            ylabel(comp.NegativePlot, 'After')
            comp.NegativePlot.XLim = [0 255];
            comp.NegativePlot.YLim = [0 255];
            comp.NegativePlot.ZLim = [0 1];
            comp.NegativePlot.XTick = [0 63 127 191 255];
            comp.NegativePlot.XTickLabel = {'0'; '63'; '127'; '191'; '255'};
            comp.NegativePlot.YTick = [0 63 127 191 255];
            comp.NegativePlot.YTickLabel = {'0'; '63'; '127'; '191'; '255'};
            comp.NegativePlot.ZTick = [];
            comp.NegativePlot.ZTickLabel = '';
            comp.NegativeEnableCheckBox = uicheckbox(comp.NegativeTab);
            comp.NegativeEnableCheckBox.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @NegativeEnableCheckBoxValueChanged, true);
            comp.NegativeEnableCheckBox.Text = 'Enable';
            comp.LogTransformTab = uitab(comp.OperationTabGroup);
            comp.LogTransformTab.Title = 'Logarithm';
            comp.LogPlot = uiaxes(comp.LogTransformTab);
            title(comp.LogPlot, 'Level mapping')
            xlabel(comp.LogPlot, 'Before')
            ylabel(comp.LogPlot, 'After')
            comp.LogPlot.XLim = [0 255];
            comp.LogPlot.YLim = [0 255];
            comp.LogPlot.ZLim = [0 1];
            comp.LogPlot.XTick = [0 63 127 191 255];
            comp.LogPlot.XTickLabel = {'0'; '63'; '127'; '191'; '255'};
            comp.LogPlot.YTick = [0 63 127 191 255];
            comp.LogPlot.YTickLabel = {'0'; '63'; '127'; '191'; '255'};
            comp.LogPlot.ZTick = [];
            comp.LogPlot.ZTickLabel = '';
            comp.MultiplierEditFieldLabel_2 = uilabel(comp.LogTransformTab);
            comp.MultiplierEditFieldLabel_2.Text = 'Multiplier';
            comp.LogMultiplierField = uieditfield(comp.LogTransformTab, 'numeric');
            comp.LogMultiplierField.Limits = [0 Inf];
            comp.LogMultiplierField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @LogMultiplierFieldValueChanged, true);
            comp.LogMultiplierField.Value = 106;
            comp.PowerTransformTab = uitab(comp.OperationTabGroup);
            comp.PowerTransformTab.Title = 'Power';
            comp.PowerPlot = uiaxes(comp.PowerTransformTab);
            title(comp.PowerPlot, 'Level mapping')
            xlabel(comp.PowerPlot, 'Before')
            ylabel(comp.PowerPlot, 'After')
            comp.PowerPlot.XLim = [0 255];
            comp.PowerPlot.YLim = [0 255];
            comp.PowerPlot.ZLim = [0 1];
            comp.PowerPlot.XTick = [0 63 127 191 255];
            comp.PowerPlot.XTickLabel = {'0'; '63'; '127'; '191'; '255'};
            comp.PowerPlot.YTick = [0 63 127 191 255];
            comp.PowerPlot.YTickLabel = {'0'; '63'; '127'; '191'; '255'};
            comp.PowerPlot.ZTick = [];
            comp.PowerPlot.ZTickLabel = '';
            comp.GammaLabel = uilabel(comp.PowerTransformTab);
            comp.GammaLabel.Text = 'Gamma';
            comp.PowerGammaField = uieditfield(comp.PowerTransformTab, 'numeric');
            comp.PowerGammaField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @PowerGammaFieldValueChanged, true);
            comp.PowerGammaField.Value = 1;
            comp.MultiplierEditFieldLabel_3 = uilabel(comp.PowerTransformTab);
            comp.MultiplierEditFieldLabel_3.Text = 'Multiplier';
            comp.PowerMultiplierField = uieditfield(comp.PowerTransformTab, 'numeric');
            comp.PowerMultiplierField.Limits = [0 Inf];
            comp.PowerMultiplierField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @PowerMultiplierFieldValueChanged, true);
            comp.PowerMultiplierField.Value = 1;
            comp.ContrastStretchingTab = uitab(comp.OperationTabGroup);
            comp.ContrastStretchingTab.Title = 'Contrast stretching';
            comp.MinimumLabel = uilabel(comp.ContrastStretchingTab);
            comp.MinimumLabel.Text = 'Minimum (R)';
            comp.MinimumLabel_2 = uilabel(comp.ContrastStretchingTab);
            comp.MinimumLabel_2.Text = 'Minimum (G)';
            comp.MinimumLabel_3 = uilabel(comp.ContrastStretchingTab);
            comp.MinimumLabel_3.Text = 'Minimum (B)';
            comp.MinimumLabel_4 = uilabel(comp.ContrastStretchingTab);
            comp.MinimumLabel_4.Text = 'Maximum (R)';
            comp.MinimumLabel_5 = uilabel(comp.ContrastStretchingTab);
            comp.MinimumLabel_5.Text = 'Maximum (G)';
            comp.CStretchingMaxBField = uieditfield(comp.ContrastStretchingTab, 'numeric');
            comp.CStretchingMaxBField.Limits = [0 255];
            comp.CStretchingMaxBField.RoundFractionalValues = 'on';
            comp.CStretchingMaxBField.AllowEmpty = 'on';
            comp.CStretchingMaxBField.Editable = 'off';
            comp.CStretchingMaxBField.Value = [];
            comp.CStretchingMaxGField = uieditfield(comp.ContrastStretchingTab, 'numeric');
            comp.CStretchingMaxGField.Limits = [0 255];
            comp.CStretchingMaxGField.RoundFractionalValues = 'on';
            comp.CStretchingMaxGField.AllowEmpty = 'on';
            comp.CStretchingMaxGField.Editable = 'off';
            comp.CStretchingMaxGField.Value = [];
            comp.MinimumLabel_6 = uilabel(comp.ContrastStretchingTab);
            comp.MinimumLabel_6.Text = 'Maximum (B)';
            comp.CStretchingMaxRField = uieditfield(comp.ContrastStretchingTab, 'numeric');
            comp.CStretchingMaxRField.Limits = [0 255];
            comp.CStretchingMaxRField.RoundFractionalValues = 'on';
            comp.CStretchingMaxRField.AllowEmpty = 'on';
            comp.CStretchingMaxRField.Editable = 'off';
            comp.CStretchingMaxRField.Value = [];
            comp.CStretchingMinBField = uieditfield(comp.ContrastStretchingTab, 'numeric');
            comp.CStretchingMinBField.Limits = [0 255];
            comp.CStretchingMinBField.RoundFractionalValues = 'on';
            comp.CStretchingMinBField.AllowEmpty = 'on';
            comp.CStretchingMinBField.Editable = 'off';
            comp.CStretchingMinBField.Value = [];
            comp.CStretchingMinGField = uieditfield(comp.ContrastStretchingTab, 'numeric');
            comp.CStretchingMinGField.Limits = [0 255];
            comp.CStretchingMinGField.RoundFractionalValues = 'on';
            comp.CStretchingMinGField.AllowEmpty = 'on';
            comp.CStretchingMinGField.Editable = 'off';
            comp.CStretchingMinGField.Value = [];
            comp.CStretchingMinRField = uieditfield(comp.ContrastStretchingTab, 'numeric');
            comp.CStretchingMinRField.Limits = [0 255];
            comp.CStretchingMinRField.RoundFractionalValues = 'on';
            comp.CStretchingMinRField.AllowEmpty = 'on';
            comp.CStretchingMinRField.Editable = 'off';
            comp.CStretchingMinRField.Value = [];
            comp.HistogramEqualizationTab = uitab(comp.OperationTabGroup);
            comp.HistogramEqualizationTab.Title = 'Histogram equalization';
            comp.HistogramMatchingTab = uitab(comp.OperationTabGroup);
            comp.HistogramMatchingTab.Title = 'Histogram matching';
            comp.ReferenceHistogramB = uiaxes(comp.HistogramMatchingTab);
            title(comp.ReferenceHistogramB, 'Reference histogram (B)')
            xlabel(comp.ReferenceHistogramB, 'Intensity')
            ylabel(comp.ReferenceHistogramB, 'Frequency')
            comp.ReferenceHistogramG = uiaxes(comp.HistogramMatchingTab);
            title(comp.ReferenceHistogramG, 'Reference histogram (G)')
            xlabel(comp.ReferenceHistogramG, 'Intensity')
            ylabel(comp.ReferenceHistogramG, 'Frequency')
            comp.ReferenceHistogramR = uiaxes(comp.HistogramMatchingTab);
            title(comp.ReferenceHistogramR, 'Reference histogram (R)')
            xlabel(comp.ReferenceHistogramR, 'Intensity')
            ylabel(comp.ReferenceHistogramR, 'Frequency')
            comp.ImageDisplayPanel = uipanel(comp);
            comp.ImageDisplayPanel.Title = 'Image display';
            comp.OutputImage = uiaxes(comp.ImageDisplayPanel);
            title(comp.OutputImage, 'Output image')
            comp.OutputImage.XTick = [];
            comp.OutputImage.YTick = [];
            comp.InputImage = uiaxes(comp.ImageDisplayPanel);
            title(comp.InputImage, 'Input image')
            comp.InputImage.XTick = [];
            comp.InputImage.YTick = [];
            comp.HistogramDisplayPanel = uipanel(comp);
            comp.HistogramDisplayPanel.Title = 'Histogram display';
            comp.OutputHistogramB = uiaxes(comp.HistogramDisplayPanel);
            title(comp.OutputHistogramB, 'Output histogram (B)')
            xlabel(comp.OutputHistogramB, 'Intensity')
            ylabel(comp.OutputHistogramB, 'Frequency')
            comp.OutputHistogramB.XLim = [0 255];
            comp.OutputHistogramG = uiaxes(comp.HistogramDisplayPanel);
            title(comp.OutputHistogramG, 'Output histogram (G)')
            xlabel(comp.OutputHistogramG, 'Intensity')
            ylabel(comp.OutputHistogramG, 'Frequency')
            comp.OutputHistogramG.XLim = [0 255];
            comp.OutputHistogramR = uiaxes(comp.HistogramDisplayPanel);
            title(comp.OutputHistogramR, 'Output histogram (R)')
            xlabel(comp.OutputHistogramR, 'Intensity')
            ylabel(comp.OutputHistogramR, 'Frequency')
            comp.OutputHistogramR.XLim = [0 255];
            comp.InputHistogramB = uiaxes(comp.HistogramDisplayPanel);
            title(comp.InputHistogramB, 'Input histogram (B)')
            xlabel(comp.InputHistogramB, 'Intensity')
            ylabel(comp.InputHistogramB, 'Frequency')
            comp.InputHistogramB.XLim = [0 255];
            comp.InputHistogramG = uiaxes(comp.HistogramDisplayPanel);
            title(comp.InputHistogramG, 'Input histogram (G)')
            xlabel(comp.InputHistogramG, 'Intensity')
            ylabel(comp.InputHistogramG, 'Frequency')
            comp.InputHistogramG.XLim = [0 255];
            comp.InputHistogramR = uiaxes(comp.HistogramDisplayPanel);
            title(comp.InputHistogramR, 'Input histogram (R)')
            xlabel(comp.InputHistogramR, 'Intensity')
            ylabel(comp.InputHistogramR, 'Frequency')
            comp.InputHistogramR.XLim = [0 255];
            comp.OperationDropDownLabel = uilabel(comp);
            comp.OperationDropDownLabel.Text = 'Operation';
            comp.OperationDropDown = uidropdown(comp);
            comp.OperationDropDown.Items = {'Brightness', 'Negative', 'Logarithm transform', 'Power transform', 'Contrast stretching', 'Histogram equalization', 'Histogram matching'};
            comp.OperationDropDown.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @OperationDropDownValueChanged, true);
            comp.OperationDropDown.Value = 'Brightness';
            comp.ApplyButton = uibutton(comp, 'push');
            comp.ApplyButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @ApplyButtonPushed, true);
            comp.ApplyButton.Enable = 'off';
            comp.ApplyButton.Text = 'Apply';
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end